classdef SystemApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup2                       matlab.ui.container.TabGroup
        SystemControlTab                matlab.ui.container.Tab
        SpectrometerParametersFullDataAcquisitionPanel  matlab.ui.container.Panel
        WavelengthnmEditField           matlab.ui.control.NumericEditField
        WavelengthnmEditFieldLabel      matlab.ui.control.Label
        SpectralResolutionnmpixelEditField  matlab.ui.control.NumericEditField
        SpectralResolutionnmpixelLabel  matlab.ui.control.Label
        PixelNumberEditField            matlab.ui.control.NumericEditField
        PixelNumberEditFieldLabel       matlab.ui.control.Label
        NumberofExposureEditField       matlab.ui.control.NumericEditField
        NumberofExposureEditFieldLabel  matlab.ui.control.Label
        ExposureTimesEditField          matlab.ui.control.NumericEditField
        ExposureTimesEditFieldLabel     matlab.ui.control.Label
        FeedbackPromptTextArea          matlab.ui.control.TextArea
        FeedbackPromptTextAreaLabel     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        TestDataAcquisitionTab          matlab.ui.container.Tab
        SpectrometerParametersTestDataAcquisitionPanel  matlab.ui.container.Panel
        EstimatedSpectralResolutionnmpixelEditField  matlab.ui.control.NumericEditField
        EstimatedSpectralResolutionnmpixelEditFieldLabel  matlab.ui.control.Label
        EstimatedPixelNumberEditField   matlab.ui.control.NumericEditField
        EstimatedPixelNumberEditFieldLabel  matlab.ui.control.Label
        EstimatedWavelengthnmEditField  matlab.ui.control.NumericEditField
        EstimatedWavelengthnmEditFieldLabel  matlab.ui.control.Label
        NumberofExposureEditField_2     matlab.ui.control.NumericEditField
        NumberofExposureEditField_2Label  matlab.ui.control.Label
        ExposureTimesEditField_2        matlab.ui.control.NumericEditField
        ExposureTimesEditField_2Label   matlab.ui.control.Label
        BottomPosEditField              matlab.ui.control.NumericEditField
        BottomPosEditFieldLabel         matlab.ui.control.Label
        TopPosEditField                 matlab.ui.control.NumericEditField
        TopPosEditFieldLabel            matlab.ui.control.Label
        TakeHyperspectralButton         matlab.ui.control.Button
        TakeBackgroundButton            matlab.ui.control.Button
        MoveButton                      matlab.ui.control.Button
        FullDataAcquisitionTab          matlab.ui.container.Tab
        BottomStepEditField             matlab.ui.control.NumericEditField
        BottomStepEditFieldLabel        matlab.ui.control.Label
        TopStepEditField                matlab.ui.control.NumericEditField
        TopStepEditFieldLabel           matlab.ui.control.Label
        BottomStopEditField             matlab.ui.control.NumericEditField
        BottomStopEditFieldLabel        matlab.ui.control.Label
        TopStopEditField                matlab.ui.control.NumericEditField
        TopStopEditFieldLabel           matlab.ui.control.Label
        BottomStartEditField            matlab.ui.control.NumericEditField
        BottomStartEditFieldLabel       matlab.ui.control.Label
        TopStartEditField               matlab.ui.control.NumericEditField
        TopStartEditFieldLabel          matlab.ui.control.Label
        MotorSettingsLabel              matlab.ui.control.Label
        InputFilenameEditField          matlab.ui.control.EditField
        InputFilenameEditFieldLabel     matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        ManualInstructionPanel          matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        DevicesIndicatorPanel           matlab.ui.container.Panel
        SpectrographLamp                matlab.ui.control.Lamp
        SpectrographLampLabel           matlab.ui.control.Label
        CCDLamp                         matlab.ui.control.Lamp
        CCDLampLabel                    matlab.ui.control.Label
        MotorsLamp                      matlab.ui.control.Lamp
        MotorsLampLabel                 matlab.ui.control.Label
        ControlPanel                    matlab.ui.container.Panel
        SetButton                       matlab.ui.control.Button
        CCDConditionLabel               matlab.ui.control.Label
        DisconnectButton                matlab.ui.control.Button
        ConnectButton                   matlab.ui.control.Button
        CCDTemperatureEditField         matlab.ui.control.NumericEditField
        CCDTemperatureLabel             matlab.ui.control.Label
        CoolerLamp                      matlab.ui.control.Lamp
        CoolerLampLabel                 matlab.ui.control.Label
        InputTemperatureEditField       matlab.ui.control.NumericEditField
        TemperatureLabel                matlab.ui.control.Label
        MotorHomedLabel                 matlab.ui.control.Label
        Lamp_Motor                      matlab.ui.control.Lamp
        HomeButton                      matlab.ui.control.Button
        BottomMotorEditField            matlab.ui.control.NumericEditField
        BottomMotorEditFieldLabel       matlab.ui.control.Label
        TopMotorEditField               matlab.ui.control.NumericEditField
        TopMotorEditFieldLabel          matlab.ui.control.Label
        MotorPostionLabel               matlab.ui.control.Label
        OptionsButtonGroup              matlab.ui.container.ButtonGroup
        CountsWavelengthButton          matlab.ui.control.RadioButton
        CountsPixelsButton              matlab.ui.control.RadioButton
        WithBackgroundCheckBox          matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        DataVisualisationTab            matlab.ui.container.Tab
        ClearButton                     matlab.ui.control.Button
        ChooseGraphButtonGroup          matlab.ui.container.ButtonGroup
        Graph1Button                    matlab.ui.control.RadioButton
        Graph2Button                    matlab.ui.control.RadioButton
        TabGroup3                       matlab.ui.container.TabGroup
        SpectrumTab                     matlab.ui.container.Tab
        DisplaySpectrumButton           matlab.ui.control.Button
        ThetaValueEditField             matlab.ui.control.NumericEditField
        ThetaValueEditFieldLabel        matlab.ui.control.Label
        PhiValueEditField               matlab.ui.control.NumericEditField
        PhiValueEditFieldLabel          matlab.ui.control.Label
        SinglePhiCutTab                 matlab.ui.container.Tab
        DisplayPhiCutButton             matlab.ui.control.Button
        PhiCutValueEditField            matlab.ui.control.NumericEditField
        PhiPixelValueEditField          matlab.ui.control.NumericEditField
        PixelValueEditFieldLabel        matlab.ui.control.Label
        PhiValueEditField_2Label        matlab.ui.control.Label
        SingleThetaCutTab               matlab.ui.container.Tab
        DisplayThetaCutButton           matlab.ui.control.Button
        ThetaCutValueEditField          matlab.ui.control.NumericEditField
        ThetaValueEditField_2Label      matlab.ui.control.Label
        ThetaPixelValueEditField        matlab.ui.control.NumericEditField
        PixelValueEditField_2Label      matlab.ui.control.Label
        ReadFilePanel                   matlab.ui.container.Panel
        ReadButton                      matlab.ui.control.Button
        FileNameEditField               matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UIDataVisualisation_2           matlab.ui.control.UIAxes
        UIDataVisualisation             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        MotorObj;
    end
    
    properties (Access = private)
        CCDfile = 'C:\Program Files\Andor SDK'
        SpectrometerDriverPath = 'C:\Program Files\Andor SDK\Device Driver';

        % CCD properties
        xpixel;
        ypixel;
        minTemp;
        maxTemp;
        arr_bkg;
        arr_hsd;

        % Spectrograph properties
        grating = 1;

        % Input properties
        top_loop;
        topstart_input;
        topstop_input;
        topstep_input;
        bottom_loop;
        bottomstart_input;
        bottomstop_input;
        bottomstep_input;
        temp_input;

        exposure_input;
        exposure_no_input;
        wavelength;
        pixel_number;
        spectral_resolution;
        topmotor_count;
        bottommotor_count;
        filename;

        % Spectrometer Input(Calibration)
        top_pos;
        bottom_pos;
        estimated_exposure_input;
        estimated_exposure_no_input;
        estimated_wavelength;
        estimated_pixel_number;
        estimated_spectral_resolution;
        test_arr_bkg;
        test_arr_hsd;

        flag_plot;
        flag_stop;
        flag_hsd;
        flag_bkg;
        flag_take_data;

        % Data Visualisation Input
        read_filename;
        Z;
        phi_val;
        theta_val;
        phicut_val;
        phipixel_val;
        thetacut_val;
        thetapixel_val;

    end
    
    methods (Access = public)
        
        function MotorInit(app)
            app.MotorObj = MotorClass;
            Load_DLLs(app.MotorObj);
            BuildConnection(app.MotorObj);
            app.MotorsLamp.Color = [0.00 1.00 0.00];
            Update_Status(app.MotorObj);
        end

        function HomeMotor(app)
            Home(app.MotorObj);
            app.TopMotorEditField.Value = 0;
            app.BottomMotorEditField.Value = 0;
            app.Lamp_Motor.Color = [0.00 1.00 0.00];
        end
        
        function MotorMove(app)
            app.Lamp_Motor.Color = [0.80 0.80 0.80];
            pause(0.5);
            TopMotor_Move(app.MotorObj, app.top_pos);
            topmotor_state = app.MotorObj.channel1.IsDeviceBusy;
            while topmotor_state ~= 0
                topmotor_state = app.MotorObj.channel1.IsDeviceBusy;
            end
            app.TopMotorEditField.Value = app.top_pos;
            pause(0.5);
            BottomMotor_Move(app.MotorObj, app.bottom_pos);
            bottommotor_state = app.MotorObj.channel2.IsDeviceBusy;
            while bottommotor_state ~= 0
                bottommotor_state = app.MotorObj.channel2.IsDeviceBusy;
            end
            app.BottomMotorEditField.Value = app.bottom_pos;
            disp('Motors are moved to desired postion.');
        end

        function MotorLoop(app, filename)
            Loop_process = false;
            app.Lamp_Motor.Color = [0.80 0.80 0.80];
            disp('Acquisition in progress...');

            for i = app.topstart_input:app.topstep_input:app.topstop_input
                TopMotor_Move(app.MotorObj, i);
                topmotor_state = app.MotorObj.channel1.IsDeviceBusy;
                while topmotor_state ~= 0
                    topmotor_state = app.MotorObj.channel1.IsDeviceBusy;
                end
                Data_taken = false;
                pause(0.5);
                app.TopMotorEditField.Value = i;

                for j = app.bottomstart_input:app.bottomstep_input:app.bottomstop_input                   
                    BottomMotor_Move(app.MotorObj, j);
                    bottommotor_state = app.MotorObj.channel2.IsDeviceBusy;
                    while bottommotor_state ~= 0
                        bottommotor_state = app.MotorObj.channel2.IsDeviceBusy;
                    end
                    pause(0.5);
                    app.BottomMotorEditField.Value = j;
                    GetHsdData(app, filename);
                    Data_taken = true;
                end
                if Data_taken ~= true
                    GetHsdData(app, filename);
                end
                Loop_process = true;
                if app.flag_stop == true
                    break;
                end
            end

            if Loop_process == false
                for j = app.bottomstart_input:app.bottomstep_input:app.bottomstop_input
                    BottomMotor_Move(app.MotorObj, j);
                    bottommotor_state = app.MotorObj.channel2.IsDeviceBusy;
                    while bottommotor_state ~= 0
                        bottommotor_state = app.MotorObj.channel2.IsDeviceBusy;
                    end
                    pause(0.5);
                    app.BottomMotorEditField.Value = j;
                    GetHsdData(app, filename);
                    if app.flag_stop == true
                        break;
                    end
                end
            end

            if app.flag_stop == true
                disp('Stop button pressed!');
                disp('Acquisition process is stopped!');
                HomeMotor(app);
            else
                disp('Acquisition Complete!')
                disp('Hyperspectral Data Saved!')
                HomeMotor(app);
            end
            
        end

        function MotorDisconnect(app)
            Disconnect(app.MotorObj);
            app.MotorsLamp.Color = [0.80 0.80 0.80];
        end
        
        function CreateHedFile(app, filename)
            app.top_loop = ((app.topstop_input - app.topstart_input)./app.topstep_input) + 1;
            app.bottom_loop = ((app.bottomstop_input - app.bottomstart_input)./app.bottomstep_input) + 1;
            hed = [app.xpixel;app.top_loop;app.topstart_input;app.topstop_input;app.topstep_input;app.bottom_loop;app.bottomstart_input;app.bottomstop_input;app.bottomstep_input;app.exposure_input;app.temp_input;app.pixel_number;app.spectral_resolution];
            File = [filename '.hed'];
            fid = fopen(File, 'w');
            fwrite(fid, hed, 'single');
            fclose(fid);
            disp('Header created!');
        end

        function CCDInit(app)
            disp('Initialising Camera');
            ret=AndorInitialize('');
            CheckError(ret);
            app.CCDLamp.Color = [0.00 1.00 0.00];

            % Set Horizontol and Vertical shift speed
            [ret] = SetHSSpeed(0, 0);                           % Output amplication set to 0 - electron multiplication. Horizontol shift speed set to 0.
            CheckWarning(ret);
            [ret, speeds] = GetNumberVSSpeeds();
            CheckWarning(ret);
            [ret] = SetVSSpeed(speeds-1);
            CheckWarning(ret);
            
            % Get the CCD size
            [ret,XPixels, YPixels]=GetDetector;                 
            CheckWarning(ret);
            app.xpixel = XPixels;
            app.ypixel = YPixels;

            % Get temperature range for CCD
            [ret, mintemp, maxtemp] = GetTemperatureRange();    
            CheckWarning(ret);
            app.minTemp = mintemp;
            app.maxTemp = maxtemp;
            
            [ret, temperature] = GetTemperature();
            CheckWarning(ret);
            app.CCDTemperatureEditField.Value = temperature;

            [ret, iCoolerStatus] = IsCoolerOn();
            CheckWarning(ret);
            if iCoolerStatus == 1
                app.CoolerLamp.Color = [0 1 0];
            else
                app.CoolerLamp.Color = [0.80 0.80 0.80];
            end

            % CCD Setup
            [ret]=SetAcquisitionMode(2);                        %   Set acquisition mode; 2 for Accumulate
            CheckWarning(ret);
            [ret]=SetReadMode(0);                               %   Set read mode; 0 for Full Vertical Binning
            CheckWarning(ret);
            [ret]=SetTriggerMode(0);                            %   Set trigger mode; 0 for Internal
            CheckWarning(ret);
            [ret]=SetShutter(1, 1, 0, 0);                       %   Open Shutter
            CheckWarning(ret);
        end

        function SetTemp(app)
            % Setup and monitor the temperature of CCD
            if app.temp_input < app.maxTemp && app.temp_input > app.minTemp
                [ret]=SetTemperature(app.temp_input);           %   Set temperature
                CheckWarning(ret);
            else
                error('The temperature is not valid. Please set the temperature again.')
            end
            disp('Temperature is set.');

            [ret, iCoolerStatus] = IsCoolerOn();
            CheckWarning(ret);
            if iCoolerStatus == 1
                app.CoolerLamp.Color = [0 1 0];
            else
                [ret]=CoolerON();                               %   Turn on temperature cooler
                CheckWarning(ret);
                app.CoolerLamp.Color = [0 1 0];
                disp('Cooling is in progress...')
            end

            [ret, temperature] = GetTemperature();              %   Get temperature
            while(ret ~= 20036)
                [ret, temperature] = GetTemperature();
                pause(2);
                app.CCDTemperatureEditField.Value = temperature;
            end
            disp('Temperature is stable and reached the input value.');
        end
        
        function GetBkgData(app, filename)
            [ret] = SetShutter(1, 2, 0, 0);                     %   Shutter set to closed
            CheckWarning(ret);

            % Start the acquisition process.
            [ret] = StartAcquisition();
            CheckWarning(ret);

            [ret, status] = GetStatus();
            while(status == atmcd.DRV_ACQUIRING)
                [ret, status] = GetStatus();
            end

            [ret] = SaveAsRaw('AcqData', 1);
            CheckWarning(ret);
            disp('Acquisition Complete!');

            fid = fopen('AcqData','r');
            arr = fread(fid, 'int16');
            fclose(fid);

            if app.flag_take_data == true
                app.arr_bkg = arr;
            else
                app.test_arr_bkg =arr;
                plot_testing_graph(app);
            end

            File = [filename '.bkg'];
            fid = fopen(File, 'w');
            fwrite(fid, arr, 'int16');
            fclose(fid);
            disp('Background data saved!');

            delete AcqData;
        end

        function GetHsdData(app, filename)
            [ret] = SetShutter(1, 0, 0, 0);                     %   Shutter set to auto
            CheckWarning(ret);

            % Start the acquisition process.
            [ret] = StartAcquisition();
            CheckWarning(ret);

            [ret, status] = GetStatus();
            while(status == atmcd.DRV_ACQUIRING)
                [ret, status] = GetStatus();
            end

            [ret] = SaveAsRaw('AcqData', 1);
            CheckWarning(ret);
            
            fid = fopen('AcqData','r');
            arr = fread(fid, 'int16');
            fclose(fid);

            if app.flag_plot == true
                app.arr_hsd = arr;
                plot_graph(app);
            else
                app.test_arr_hsd = arr;
                plot_testing_graph(app);
            end
            
            File = [filename '.hsd'];
            fid = fopen(File, 'a');
            fwrite(fid, arr, 'uint16');
            fclose(fid);

            delete AcqData;
        end
        
        function plot_graph(app)
            if app.WithBackgroundCheckBox.Value == 1
                if app.CountsPixelsButton.Value == 1
                    I = app.arr_hsd - app.arr_bkg;
                    plot(app.UIAxes, I);
                    xlim(app.UIAxes, [0 1024]);
                    xlabel(app.UIAxes, "Spectrum Point");
                    ylabel(app.UIAxes, "Counts");

                elseif app.CountsWavelengthButton.Value == 1
                    X = linspace(app.wavelength, app.wavelength+1024*app.spectral_resolution, 1024);
                    Y = app.arr_hsd - app.arr_bkg;
                    plot(app.UIAxes, X, Y);
                    xlim(app.UIAxes, [app.wavelength app.wavelength+1024*app.spectral_resolution]);
                    xlabel(app.UIAxes, "Wavelengths");
                    ylabel(app.UIAxes, "Counts");
                end
            else
                if app.CountsPixelsButton.Value == 1
                    I = app.arr_hsd;
                    plot(app.UIAxes, I);
                    xlim(app.UIAxes, [0 1024]);
                    xlabel(app.UIAxes, "Spectrum Point");
                    ylabel(app.UIAxes, "Counts");

                elseif app.CountsWavelengthButton.Value == 1
                    X = linspace(app.wavelength, app.wavelength+1024*app.spectral_resolution, 1024);
                    Y = app.arr_hsd;
                    plot(app.UIAxes, X, Y);
                    xlim(app.UIAxes, [app.wavelength app.wavelength+1024*app.spectral_resolution]);
                    xlabel(app.UIAxes, "Wavelengths");
                    ylabel(app.UIAxes, "Counts");
                end
            end
        end

        function plot_testing_graph(app)
            if app.WithBackgroundCheckBox.Value == 1
                if app.flag_hsd == true
                    if app.CountsPixelsButton.Value == 1
                        I = app.test_arr_hsd - app.test_arr_bkg;
                        plot(app.UIAxes, I);
                        xlim(app.UIAxes, [0 1024]);
                        xlabel(app.UIAxes, "Spectrum Point");
                        ylabel(app.UIAxes, "Counts");

                    elseif app.CountsWavelengthButton.Value == 1
                        X = linspace(app.estimated_wavelength, app.estimated_wavelength+1024*app.estimated_spectral_resolution, 1024);
                        Y = app.test_arr_hsd - app.test_arr_bkg;
                        plot(app.UIAxes, X, Y);
                        xlim(app.UIAxes, [app.estimated_wavelength app.estimated_wavelength+1024*app.estimated_spectral_resolution]);
                        xlabel(app.UIAxes, "Wavelengths");
                        ylabel(app.UIAxes, "Counts");
                    end
                else
                    if app.CountsPixelsButton.Value == 1
                        I = app.test_arr_bkg;
                        plot(app.UIAxes, I);
                        xlim(app.UIAxes, [0 1024]);
                        xlabel(app.UIAxes, "Spectrum Point");
                        ylabel(app.UIAxes, "Counts");

                    elseif app.CountsWavelengthButton.Value == 1
                        X = linspace(app.estimated_wavelength, app.estimated_wavelength+1024*app.estimated_spectral_resolution, 1024);
                        Y = app.test_arr_bkg;
                        plot(app.UIAxes, X, Y);
                        xlim(app.UIAxes, [app.estimated_wavelength app.estimated_wavelength+1024*app.estimated_spectral_resolution]);
                        xlabel(app.UIAxes, "Wavelengths");
                        ylabel(app.UIAxes, "Counts");
                    end
                end
            else
                if app.flag_hsd == true
                    if app.CountsPixelsButton.Value == 1
                        I = app.test_arr_hsd;
                        plot(app.UIAxes, I);
                        xlim(app.UIAxes, [0 1024]);
                        xlabel(app.UIAxes, "Spectrum Point");
                        ylabel(app.UIAxes, "Counts");

                    elseif app.CountsWavelengthButton.Value == 1
                        X = linspace(app.estimated_wavelength, app.estimated_wavelength+1024*app.estimated_spectral_resolution, 1024);
                        Y = app.test_arr_hsd;
                        plot(app.UIAxes, X, Y);
                        xlim(app.UIAxes, [app.estimated_wavelength app.estimated_wavelength+1024*app.estimated_spectral_resolution]);
                        xlabel(app.UIAxes, "Wavelengths");
                        ylabel(app.UIAxes, "Counts");
                    end
                else
                    if app.CountsPixelsButton.Value == 1
                        I = app.test_arr_bkg;
                        plot(app.UIAxes, I);
                        xlim(app.UIAxes, [0 1024]);
                        xlabel(app.UIAxes, "Spectrum Point");
                        ylabel(app.UIAxes, "Counts");

                    elseif app.CountsWavelengthButton.Value == 1
                        X = linspace(app.estimated_wavelength, app.estimated_wavelength+1024*app.estimated_spectral_resolution, 1024);
                        Y = app.test_arr_bkg;
                        plot(app.UIAxes, X, Y);
                        xlim(app.UIAxes, [app.estimated_wavelength app.estimated_wavelength+1024*app.estimated_spectral_resolution]);
                        xlabel(app.UIAxes, "Wavelengths");
                        ylabel(app.UIAxes, "Counts");
                    end
                end
            end
        end

        function SpectrometerSetup(app, wavelength, exposure_time, no_exposure)
            % Setup the wavelength in spectrograph
            [ret, wavelength_present] = ATSpectrographWavelengthIsPresent(0);
            ATSpectrographCheckWarning(ret);
            [ret] = ATSpectrographWavelengthReset(0);
            ATSpectrographCheckWarning(ret);
            if wavelength_present > 0
                [ret, min, max] = ATSpectrographGetWavelengthLimits(0, app.grating);
                ATSpectrographCheckWarning(ret);

                if wavelength < max && wavelength >min
                    [ret] = ATSpectrographSetWavelength(0, wavelength);
                    ATSpectrographCheckWarning(ret);
                else
                    error('Wavelength is out of range!')
                end
                [ret, wavelength_info] = ATSpectrographGetWavelength(0);
                ATSpectrographCheckWarning(ret);
                if wavelength_info == wavelength
                    disp('Wavelength of spectrograph is set sucessfully!')
                else
                    disp('The wavelength is not set sucessfully!')
                end
            else
                disp('Wavelength drive is not detected!')
            end

            % CCD Setup from input
            [ret]=SetNumberAccumulations(no_exposure);%   Set number of accumulations
            CheckWarning(ret);
            [ret]=SetExposureTime(exposure_time);          %   Set exposure time in second
            CheckWarning(ret);
        end

        function CCDshutdown(app)
            [ret, status] = GetStatus();
            while(status ~= atmcd.DRV_IDLE)
                [ret, status] = GetStatus();
            end

            % Turn off the cooler and monitor the temperature
            [ret, iCoolerStatus] = IsCoolerOn();
            CheckWarning(ret);
            if iCoolerStatus == 1
                [ret] = CoolerOFF();
                CheckWarning(ret);
                app.CoolerLamp.Color = [0.80 0.80 0.80];
            else
                disp('Cooler is off!')
            end
            warning('Cooler is turned off and waiting for temperature of CCD rise.');
            warning('Do not cut off the power!!!');
            [ret, temperature] = GetTemperature();              %   Get temperature
            while(temperature < -10)
                [ret, temperature] = GetTemperature();
                pause(2);
                app.CCDTemperatureEditField.Value = temperature;
            end

            disp('Cleaning Up and Shutting Down');
            [ret]=SetShutter(1, 2, 1, 1);
            CheckWarning(ret);
            [ret]=AndorShutDown;
            CheckWarning(ret);
            app.CCDLamp.Color = [0.80 0.80 0.80];
            disp('CCD is shut down sucessfully!');
        end

        function SpectrographInit(app)
            [ret] = ATSpectrographInitialize('');
            ATSpectrographCheckError(ret);
            app.SpectrographLamp.Color = [0.00 1.00 0.00];

            % Setup the grating
            [ret, grating_present] = ATSpectrographGratingIsPresent(0);
            ATSpectrographCheckWarning(ret);
            if grating_present > 0
                [ret] = ATSpectrographSetGrating(0, app.grating);
                ATSpectrographCheckWarning(ret);
                [ret, grating_info] = ATSpectrographGetGrating(0);
                ATSpectrographCheckWarning(ret);
                if grating_info == app.grating
                    disp('Grating of spectrograph is set sucessfully!')
                else
                    error('The grating is not set sucessfully!')
                end
            else
                disp('Grating turret is not detected!')
            end
        end

        function SpectrographShutDown(app)
            [ret] = ATSpectrographClose();
            ATSpectrographCheckWarning(ret);
            app.SpectrographLamp.Color = [0.80 0.80 0.80];
            disp('Spectrograph is closed successfully!');
        end

end

    
    methods (Access = private)
        
        function setPromptFcn(app,jTextArea,eventData,newPrompt)
            % Prevent overlapping reentry due to prompt replacement
            persistent inProgress
            if isempty(inProgress)
                inProgress = 1;  %#ok unused
            else
                return;
            end

            try
                % *** Prompt modification code goes here ***
                cwText = char(jTextArea.getText);
                app.FeedbackPromptTextArea.Value = cwText;
                % force prompt-change callback to fizzle-out...
                pause(0.02);
            catch
                % Never mind - ignore errors...
            end

            % Enable new callbacks now that the prompt has been modified
            inProgress = [];

        end  % setPromptFcn

        function CloseApp(app, event)
            app.delete % Shut down the entire app
        end

        function SpectrumPoint(app)
            if app.Graph1Button.Value == 1
                [I] = displayspectrum(app.Z, app.theta_val, app.phi_val, 'Title');
                plot(app.UIDataVisualisation, I);
                title(app.UIDataVisualisation,"Spectrum Data");
                xlim(app.UIDataVisualisation, [0 1024]);
                xlabel(app.UIDataVisualisation, "Spectrum Point");
                ylabel(app.UIDataVisualisation, "Counts");
            elseif app.Graph2Button.Value == 1
                [I] = displayspectrum(app.Z, app.theta_val, app.phi_val, 'Title');
                plot(app.UIDataVisualisation_2, I);
                title(app.UIDataVisualisation_2,"Spectrum Data");
                xlim(app.UIDataVisualisation_2, [0 1024]);
                xlabel(app.UIDataVisualisation_2, "Spectrum Point");
                ylabel(app.UIDataVisualisation_2, "Counts");
            end
        end

        function SinglePhiCut(app)
            if app.Graph1Button.Value == 1
                [I h] = singlephicut(app.Z, app.phicut_val, app.phipixel_val, 'Title');
                plot(app.UIDataVisualisation, I);
                title(app.UIDataVisualisation,"Single Phi Cut");
                xlim(app.UIDataVisualisation, [app.Z.thetastart app.Z.thetastop]);
                xlabel(app.UIDataVisualisation, "Theta Position");
                ylabel(app.UIDataVisualisation, "Counts");
            elseif app.Graph2Button.Value == 1
                [I h] = singlephicut(app.Z, app.phicut_val, app.phipixel_val, 'Title');
                plot(app.UIDataVisualisation_2, I);
                title(app.UIDataVisualisation_2,"Single Phi Cut");
                xlim(app.UIDataVisualisation_2, [app.Z.thetastart app.Z.thetastop]);
                xlabel(app.UIDataVisualisation_2, "Theta Position");
                ylabel(app.UIDataVisualisation_2, "Counts");
            end
        end

            function SingleThetaCut(app)
                if app.Graph1Button.Value == 1
                    [I h] = singlethetacut(app.Z, app.thetacut_val, app.thetapixel_val, 'Title');
                    plot(app.UIDataVisualisation, I);
                    title(app.UIDataVisualisation,"Single Theta Cut");
                    xlim(app.UIDataVisualisation, [app.Z.thetastart app.Z.thetastop]);
                    xlabel(app.UIDataVisualisation, "Phi Position");
                    ylabel(app.UIDataVisualisation, "Counts");
                elseif app.Graph2Button.Value == 1
                    [I h] = singlethetacut(app.Z, app.thetacut_val, app.thetapixel_val, 'Title');
                    plot(app.UIDataVisualisation_2, I);
                    title(app.UIDataVisualisation_2,"Single Theta Cut");
                    xlim(app.UIDataVisualisation_2, [app.Z.thetastart app.Z.thetastop]);
                    xlabel(app.UIDataVisualisation_2, "Phi Position");
                    ylabel(app.UIDataVisualisation_2, "Counts");
                end
            end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                jDesktop = com.mathworks.mde.desk.MLDesktop.getInstance;
                jCmdWin = jDesktop.getClient('Command Window');
                jTextArea = jCmdWin.getComponent(0).getViewport.getView;
                set(jTextArea,'CaretUpdateCallback',@app.setPromptFcn)
            catch
                warndlg('fatal error');
            end
            
            % Add the path of Andor ToolBox
            addpath('C:\Users\Public\Documents\Matlab\ToolBox\Andor');
            % Add the path of data visualisation functions
            addpath('C:\Users\jiali\OneDrive\Desktop\MSc Dissertation\Previous System\HSI Matlab');
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            CCDInit(app);
            pause(1);

            SpectrographInit(app);
            pause(1);

            MotorInit(app);
            pause(1);

            HomeMotor(app);
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            MotorDisconnect(app);
            pause(2);

            SpectrographShutDown(app);
            pause(2);

            CCDshutdown(app);
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            SetTemp(app);
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            HomeMotor(app);
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            MotorMove(app);
            
        end

        % Button pushed function: TakeBackgroundButton
        function TakeBackgroundButtonPushed(app, event)
            SpectrometerSetup(app, app.estimated_wavelength, app.estimated_exposure_input, app.estimated_exposure_no_input);
            app.flag_plot = false;
            app.flag_hsd = false;
            GetBkgData(app, 'Testing');
            delete Testing.bkg;
        end

        % Button pushed function: TakeHyperspectralButton
        function TakeHyperspectralButtonPushed(app, event)
            SpectrometerSetup(app, app.estimated_wavelength, app.estimated_exposure_input, app.estimated_exposure_no_input);
            app.flag_plot = false;
            app.flag_hsd = true;
            GetHsdData(app, 'Testing');
            delete Testing.hsd;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            SpectrometerSetup(app, app.wavelength, app.exposure_input, app.exposure_no_input);
            app.flag_plot = true;
            app.flag_hsd = true;
            app.flag_stop = false;
            app.flag_take_data = true;

            % Take background data
            GetBkgData(app, app.filename);
            CreateHedFile(app, app.filename);

            % Start motor loops and take hyperspectral data
            MotorLoop(app, app.filename);
            pause(0.5);
            app.flag_take_data = false;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.flag_stop = true;
        end

        % Value changed function: TopStartEditField
        function TopStartEditFieldValueChanged(app, event)
            app.topstart_input = app.TopStartEditField.Value;
        end

        % Value changed function: BottomStartEditField
        function BottomStartEditFieldValueChanged(app, event)
            app.bottomstart_input = app.BottomStartEditField.Value;
        end

        % Value changed function: TopStopEditField
        function TopStopEditFieldValueChanged(app, event)
            app.topstop_input = app.TopStopEditField.Value;
        end

        % Value changed function: BottomStopEditField
        function BottomStopEditFieldValueChanged(app, event)
            app.bottomstop_input = app.BottomStopEditField.Value;
        end

        % Value changed function: TopStepEditField
        function TopStepEditFieldValueChanged(app, event)
            app.topstep_input = app.TopStepEditField.Value;
        end

        % Value changed function: BottomStepEditField
        function BottomStepEditFieldValueChanged(app, event)
            app.bottomstep_input = app.BottomStepEditField.Value;
        end

        % Value changed function: InputTemperatureEditField
        function InputTemperatureEditFieldValueChanged(app, event)
            app.temp_input = app.InputTemperatureEditField.Value;           
        end

        % Value changed function: ExposureTimesEditField
        function ExposureTimesEditFieldValueChanged(app, event)
            app.exposure_input = app.ExposureTimesEditField.Value;            
        end

        % Value changed function: NumberofExposureEditField
        function NumberofExposureEditFieldValueChanged(app, event)
            app.exposure_no_input = app.NumberofExposureEditField.Value;          
        end

        % Value changed function: WavelengthnmEditField
        function WavelengthnmEditFieldValueChanged(app, event)
            app.wavelength = app.WavelengthnmEditField.Value;
        end

        % Value changed function: PixelNumberEditField
        function PixelNumberEditFieldValueChanged(app, event)
            app.pixel_number = app.PixelNumberEditField.Value;
        end

        % Value changed function: SpectralResolutionnmpixelEditField
        function SpectralResolutionnmpixelEditFieldValueChanged(app, event)
            app.spectral_resolution = app.SpectralResolutionnmpixelEditField.Value;            
        end

        % Value changed function: InputFilenameEditField
        function InputFilenameEditFieldValueChanged(app, event)
            app.filename = app.InputFilenameEditField.Value;
        end

        % Selection changed function: OptionsButtonGroup
        function OptionsButtonGroupSelectionChanged(app, event)
            if app.flag_plot == true
                plot_graph(app);
            else
                plot_testing_graph(app);
            end
        end

        % Value changed function: WithBackgroundCheckBox
        function WithBackgroundCheckBoxValueChanged(app, event)
            if app.flag_plot == true
                plot_graph(app);
            else
                plot_testing_graph(app);
            end
        end

        % Value changed function: TopPosEditField
        function TopPosEditFieldValueChanged(app, event)
             app.top_pos = app.TopPosEditField.Value;
            
        end

        % Value changed function: BottomPosEditField
        function BottomPosEditFieldValueChanged(app, event)
            app.bottom_pos = app.BottomPosEditField.Value;
            
        end

        % Value changed function: ExposureTimesEditField_2
        function ExposureTimesEditField_2ValueChanged(app, event)
            app.estimated_exposure_input = app.ExposureTimesEditField_2.Value;
            
        end

        % Value changed function: NumberofExposureEditField_2
        function NumberofExposureEditField_2ValueChanged(app, event)
            app.estimated_exposure_no_input = app.NumberofExposureEditField_2.Value;
            
        end

        % Value changed function: EstimatedWavelengthnmEditField
        function EstimatedWavelengthnmEditFieldValueChanged(app, event)
            app.estimated_wavelength = app.EstimatedWavelengthnmEditField.Value;
        end

        % Value changed function: EstimatedPixelNumberEditField
        function EstimatedPixelNumberEditFieldValueChanged(app, event)
            app.estimated_pixel_number = app.EstimatedPixelNumberEditField.Value;
            
        end

        % Value changed function: 
        % EstimatedSpectralResolutionnmpixelEditField
        function EstimatedSpectralResolutionnmpixelEditFieldValueChanged(app, event)
            app.estimated_spectral_resolution = app.EstimatedSpectralResolutionnmpixelEditField.Value;
            
        end

        % Callback function
        function FilePathEditFieldValueChanged(app, event)
            filepath = app.FilePathEditField.Value;
            addpath(filepath);
        end

        % Value changed function: FileNameEditField
        function FileNameEditFieldValueChanged(app, event)
            app.read_filename = app.FileNameEditField.Value;
            
        end

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            try
                [app.Z] = readdatafile(app.read_filename);
                Field = {'spect';'phi';'phistart';'phistop';'phistep';'theta';'thetastart';'thetastop';'thetastep'};
                Value = [app.Z.spect app.Z.phi app.Z.phistart app.Z.phistop app.Z.phires app.Z.theta app.Z.thetastart app.Z.thetastop app.Z.thetares]';
                T = table(Field, Value);
                app.UITable.Data = T;
                app.UITable.ColumnName = T.Properties.VariableNames;
            catch
                error('Filename is not valid!');
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIDataVisualisation);
            cla(app.UIDataVisualisation_2);
        end

        % Value changed function: PhiValueEditField
        function PhiValueEditFieldValueChanged(app, event)
            app.phi_val = app.PhiValueEditField.Value;
            
        end

        % Value changed function: ThetaValueEditField
        function ThetaValueEditFieldValueChanged(app, event)
            app.theta_val = app.ThetaValueEditField.Value;
            
        end

        % Value changed function: PhiCutValueEditField
        function PhiCutValueEditFieldValueChanged(app, event)
            app.phicut_val = app.PhiCutValueEditField.Value;
            
        end

        % Value changed function: PhiPixelValueEditField
        function PhiPixelValueEditFieldValueChanged(app, event)
            app.phipixel_val = app.PhiPixelValueEditField.Value;
            
        end

        % Value changed function: ThetaCutValueEditField
        function ThetaCutValueEditFieldValueChanged(app, event)
            app.thetacut_val = app.ThetaCutValueEditField.Value;
            
        end

        % Value changed function: ThetaPixelValueEditField
        function ThetaPixelValueEditFieldValueChanged(app, event)
            app.thetapixel_val = app.ThetaPixelValueEditField.Value;
            
        end

        % Button pushed function: DisplaySpectrumButton
        function DisplaySpectrumButtonPushed(app, event)
            try
                SpectrumPoint(app);
            catch
                error ('Input error value!');
            end
        end

        % Button pushed function: DisplayPhiCutButton
        function DisplayPhiCutButtonPushed(app, event)
            try
                SinglePhiCut(app);
            catch
                error ('Input error value!');
            end
        end

        % Button pushed function: DisplayThetaCutButton
        function DisplayThetaCutButtonPushed(app, event)
            try
                SingleThetaCut(app);
            catch
                error ('Input error value!');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.SystemControlTab = uitab(app.TabGroup2);
            app.SystemControlTab.Title = 'System Control';
            app.UIAxes = uiaxes(app.SystemControlTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.WithBackgroundCheckBox = uicheckbox(app.SystemControlTab);
            app.WithBackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @WithBackgroundCheckBoxValueChanged, true);
            app.WithBackgroundCheckBox.Text = 'With Background';
            app.OptionsButtonGroup = uibuttongroup(app.SystemControlTab);
            app.OptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionsButtonGroupSelectionChanged, true);
            app.OptionsButtonGroup.Title = 'Options';
            app.CountsPixelsButton = uiradiobutton(app.OptionsButtonGroup);
            app.CountsPixelsButton.Text = 'Counts/Pixels';
            app.CountsPixelsButton.Value = true;
            app.CountsWavelengthButton = uiradiobutton(app.OptionsButtonGroup);
            app.CountsWavelengthButton.Text = 'Counts/Wavelength';
            app.ControlPanel = uipanel(app.SystemControlTab);
            app.ControlPanel.Title = 'Control Panel';
            app.MotorPostionLabel = uilabel(app.ControlPanel);
            app.MotorPostionLabel.Text = 'Motor Postion';
            app.TopMotorEditFieldLabel = uilabel(app.ControlPanel);
            app.TopMotorEditFieldLabel.Text = 'Top Motor';
            app.TopMotorEditField = uieditfield(app.ControlPanel, 'numeric');
            app.TopMotorEditField.Limits = [0 360];
            app.TopMotorEditField.Editable = 'off';
            app.BottomMotorEditFieldLabel = uilabel(app.ControlPanel);
            app.BottomMotorEditFieldLabel.Text = 'Bottom Motor';
            app.BottomMotorEditField = uieditfield(app.ControlPanel, 'numeric');
            app.BottomMotorEditField.Limits = [-90 90];
            app.BottomMotorEditField.Editable = 'off';
            app.HomeButton = uibutton(app.ControlPanel, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.Lamp_Motor = uilamp(app.ControlPanel);
            app.MotorHomedLabel = uilabel(app.ControlPanel);
            app.MotorHomedLabel.Text = 'Motor Homed?';
            app.TemperatureLabel = uilabel(app.ControlPanel);
            app.TemperatureLabel.Text = 'Input Temperature';
            app.InputTemperatureEditField = uieditfield(app.ControlPanel, 'numeric');
            app.InputTemperatureEditField.Limits = [-Inf 20];
            app.InputTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @InputTemperatureEditFieldValueChanged, true);
            app.CoolerLampLabel = uilabel(app.ControlPanel);
            app.CoolerLampLabel.Text = 'Cooler';
            app.CoolerLamp = uilamp(app.ControlPanel);
            app.CCDTemperatureLabel = uilabel(app.ControlPanel);
            app.CCDTemperatureLabel.Text = 'CCD Temperature';
            app.CCDTemperatureEditField = uieditfield(app.ControlPanel, 'numeric');
            app.CCDTemperatureEditField.Limits = [-90 90];
            app.CCDTemperatureEditField.Editable = 'off';
            app.ConnectButton = uibutton(app.ControlPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.ControlPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.CCDConditionLabel = uilabel(app.ControlPanel);
            app.CCDConditionLabel.Text = 'CCD Condition';
            app.SetButton = uibutton(app.ControlPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.DevicesIndicatorPanel = uipanel(app.SystemControlTab);
            app.DevicesIndicatorPanel.Title = 'Devices Indicator';
            app.MotorsLampLabel = uilabel(app.DevicesIndicatorPanel);
            app.MotorsLampLabel.Text = 'Motors';
            app.MotorsLamp = uilamp(app.DevicesIndicatorPanel);
            app.CCDLampLabel = uilabel(app.DevicesIndicatorPanel);
            app.CCDLampLabel.Text = 'CCD';
            app.CCDLamp = uilamp(app.DevicesIndicatorPanel);
            app.SpectrographLampLabel = uilabel(app.DevicesIndicatorPanel);
            app.SpectrographLampLabel.Text = 'Spectrograph';
            app.SpectrographLamp = uilamp(app.DevicesIndicatorPanel);
            app.ManualInstructionPanel = uipanel(app.SystemControlTab);
            app.ManualInstructionPanel.Title = 'Manual & Instruction';
            app.TextArea = uitextarea(app.ManualInstructionPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'1. Before starting any data acquisition, please set the '; '    temperature for CCD in the control panel.'; ''; '2. Please do not cut off the power of CCD until the '; '    the temperature of CCD rises above -10 degree '; '    Celcius.'; ''};
            app.TabGroup = uitabgroup(app.SystemControlTab);
            app.TestDataAcquisitionTab = uitab(app.TabGroup);
            app.TestDataAcquisitionTab.Title = 'Test Data Acquisition';
            app.MoveButton = uibutton(app.TestDataAcquisitionTab, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.TakeBackgroundButton = uibutton(app.TestDataAcquisitionTab, 'push');
            app.TakeBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @TakeBackgroundButtonPushed, true);
            app.TakeBackgroundButton.Text = 'Take Background';
            app.TakeHyperspectralButton = uibutton(app.TestDataAcquisitionTab, 'push');
            app.TakeHyperspectralButton.ButtonPushedFcn = createCallbackFcn(app, @TakeHyperspectralButtonPushed, true);
            app.TakeHyperspectralButton.Text = 'Take Hyperspectral';
            app.TopPosEditFieldLabel = uilabel(app.TestDataAcquisitionTab);
            app.TopPosEditFieldLabel.Text = 'Top Pos';
            app.TopPosEditField = uieditfield(app.TestDataAcquisitionTab, 'numeric');
            app.TopPosEditField.Limits = [0 360];
            app.TopPosEditField.ValueChangedFcn = createCallbackFcn(app, @TopPosEditFieldValueChanged, true);
            app.BottomPosEditFieldLabel = uilabel(app.TestDataAcquisitionTab);
            app.BottomPosEditFieldLabel.Text = 'Bottom Pos';
            app.BottomPosEditField = uieditfield(app.TestDataAcquisitionTab, 'numeric');
            app.BottomPosEditField.Limits = [-90 90];
            app.BottomPosEditField.ValueChangedFcn = createCallbackFcn(app, @BottomPosEditFieldValueChanged, true);
            app.SpectrometerParametersTestDataAcquisitionPanel = uipanel(app.TestDataAcquisitionTab);
            app.SpectrometerParametersTestDataAcquisitionPanel.Title = 'Spectrometer Parameters (Test Data Acquisition)';
            app.ExposureTimesEditField_2Label = uilabel(app.SpectrometerParametersTestDataAcquisitionPanel);
            app.ExposureTimesEditField_2Label.Text = 'Exposure Time (s)';
            app.ExposureTimesEditField_2 = uieditfield(app.SpectrometerParametersTestDataAcquisitionPanel, 'numeric');
            app.ExposureTimesEditField_2.ValueChangedFcn = createCallbackFcn(app, @ExposureTimesEditField_2ValueChanged, true);
            app.NumberofExposureEditField_2Label = uilabel(app.SpectrometerParametersTestDataAcquisitionPanel);
            app.NumberofExposureEditField_2Label.Text = 'Number of Exposure ';
            app.NumberofExposureEditField_2 = uieditfield(app.SpectrometerParametersTestDataAcquisitionPanel, 'numeric');
            app.NumberofExposureEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumberofExposureEditField_2ValueChanged, true);
            app.EstimatedWavelengthnmEditFieldLabel = uilabel(app.SpectrometerParametersTestDataAcquisitionPanel);
            app.EstimatedWavelengthnmEditFieldLabel.Text = 'Estimated Wavelength (nm)';
            app.EstimatedWavelengthnmEditField = uieditfield(app.SpectrometerParametersTestDataAcquisitionPanel, 'numeric');
            app.EstimatedWavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @EstimatedWavelengthnmEditFieldValueChanged, true);
            app.EstimatedPixelNumberEditFieldLabel = uilabel(app.SpectrometerParametersTestDataAcquisitionPanel);
            app.EstimatedPixelNumberEditFieldLabel.Text = 'Estimated Pixel Number ';
            app.EstimatedPixelNumberEditField = uieditfield(app.SpectrometerParametersTestDataAcquisitionPanel, 'numeric');
            app.EstimatedPixelNumberEditField.ValueChangedFcn = createCallbackFcn(app, @EstimatedPixelNumberEditFieldValueChanged, true);
            app.EstimatedSpectralResolutionnmpixelEditFieldLabel = uilabel(app.SpectrometerParametersTestDataAcquisitionPanel);
            app.EstimatedSpectralResolutionnmpixelEditFieldLabel.Text = 'Estimated Spectral Resolution (nm/pixel)';
            app.EstimatedSpectralResolutionnmpixelEditField = uieditfield(app.SpectrometerParametersTestDataAcquisitionPanel, 'numeric');
            app.EstimatedSpectralResolutionnmpixelEditField.ValueChangedFcn = createCallbackFcn(app, @EstimatedSpectralResolutionnmpixelEditFieldValueChanged, true);
            app.FullDataAcquisitionTab = uitab(app.TabGroup);
            app.FullDataAcquisitionTab.Title = 'Full Data Acquisition';
            app.StartButton = uibutton(app.FullDataAcquisitionTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.FullDataAcquisitionTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.InputFilenameEditFieldLabel = uilabel(app.FullDataAcquisitionTab);
            app.InputFilenameEditFieldLabel.Text = 'Input Filename';
            app.InputFilenameEditField = uieditfield(app.FullDataAcquisitionTab, 'text');
            app.InputFilenameEditField.ValueChangedFcn = createCallbackFcn(app, @InputFilenameEditFieldValueChanged, true);
            app.MotorSettingsLabel = uilabel(app.FullDataAcquisitionTab);
            app.MotorSettingsLabel.Text = 'Motor Settings';
            app.TopStartEditFieldLabel = uilabel(app.FullDataAcquisitionTab);
            app.TopStartEditFieldLabel.Text = 'Top Start';
            app.TopStartEditField = uieditfield(app.FullDataAcquisitionTab, 'numeric');
            app.TopStartEditField.Limits = [0 360];
            app.TopStartEditField.ValueChangedFcn = createCallbackFcn(app, @TopStartEditFieldValueChanged, true);
            app.TopStartEditField.Value = 5;
            app.BottomStartEditFieldLabel = uilabel(app.FullDataAcquisitionTab);
            app.BottomStartEditFieldLabel.Text = 'Bottom Start';
            app.BottomStartEditField = uieditfield(app.FullDataAcquisitionTab, 'numeric');
            app.BottomStartEditField.Limits = [-90 90];
            app.BottomStartEditField.ValueChangedFcn = createCallbackFcn(app, @BottomStartEditFieldValueChanged, true);
            app.BottomStartEditField.Value = 5;
            app.TopStopEditFieldLabel = uilabel(app.FullDataAcquisitionTab);
            app.TopStopEditFieldLabel.Text = 'Top Stop';
            app.TopStopEditField = uieditfield(app.FullDataAcquisitionTab, 'numeric');
            app.TopStopEditField.Limits = [0 360];
            app.TopStopEditField.ValueChangedFcn = createCallbackFcn(app, @TopStopEditFieldValueChanged, true);
            app.TopStopEditField.Value = 10;
            app.BottomStopEditFieldLabel = uilabel(app.FullDataAcquisitionTab);
            app.BottomStopEditFieldLabel.Text = 'Bottom Stop';
            app.BottomStopEditField = uieditfield(app.FullDataAcquisitionTab, 'numeric');
            app.BottomStopEditField.Limits = [-90 90];
            app.BottomStopEditField.ValueChangedFcn = createCallbackFcn(app, @BottomStopEditFieldValueChanged, true);
            app.BottomStopEditField.Value = 10;
            app.TopStepEditFieldLabel = uilabel(app.FullDataAcquisitionTab);
            app.TopStepEditFieldLabel.Text = 'Top Step';
            app.TopStepEditField = uieditfield(app.FullDataAcquisitionTab, 'numeric');
            app.TopStepEditField.ValueChangedFcn = createCallbackFcn(app, @TopStepEditFieldValueChanged, true);
            app.TopStepEditField.Value = 0.2;
            app.BottomStepEditFieldLabel = uilabel(app.FullDataAcquisitionTab);
            app.BottomStepEditFieldLabel.Text = 'Bottom Step';
            app.BottomStepEditField = uieditfield(app.FullDataAcquisitionTab, 'numeric');
            app.BottomStepEditField.ValueChangedFcn = createCallbackFcn(app, @BottomStepEditFieldValueChanged, true);
            app.BottomStepEditField.Value = 0.2;
            app.FeedbackPromptTextAreaLabel = uilabel(app.SystemControlTab);
            app.FeedbackPromptTextAreaLabel.Text = 'Feedback Prompt';
            app.FeedbackPromptTextArea = uitextarea(app.SystemControlTab);
            app.SpectrometerParametersFullDataAcquisitionPanel = uipanel(app.SystemControlTab);
            app.SpectrometerParametersFullDataAcquisitionPanel.Title = 'Spectrometer Parameters (Full Data Acquisition)';
            app.ExposureTimesEditFieldLabel = uilabel(app.SpectrometerParametersFullDataAcquisitionPanel);
            app.ExposureTimesEditFieldLabel.Text = 'Exposure Time (s)';
            app.ExposureTimesEditField = uieditfield(app.SpectrometerParametersFullDataAcquisitionPanel, 'numeric');
            app.ExposureTimesEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimesEditFieldValueChanged, true);
            app.NumberofExposureEditFieldLabel = uilabel(app.SpectrometerParametersFullDataAcquisitionPanel);
            app.NumberofExposureEditFieldLabel.Text = 'Number of Exposure ';
            app.NumberofExposureEditField = uieditfield(app.SpectrometerParametersFullDataAcquisitionPanel, 'numeric');
            app.NumberofExposureEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofExposureEditFieldValueChanged, true);
            app.PixelNumberEditFieldLabel = uilabel(app.SpectrometerParametersFullDataAcquisitionPanel);
            app.PixelNumberEditFieldLabel.Text = 'Pixel Number ';
            app.PixelNumberEditField = uieditfield(app.SpectrometerParametersFullDataAcquisitionPanel, 'numeric');
            app.PixelNumberEditField.ValueChangedFcn = createCallbackFcn(app, @PixelNumberEditFieldValueChanged, true);
            app.SpectralResolutionnmpixelLabel = uilabel(app.SpectrometerParametersFullDataAcquisitionPanel);
            app.SpectralResolutionnmpixelLabel.Text = 'Spectral Resolution (nm/pixel)';
            app.SpectralResolutionnmpixelEditField = uieditfield(app.SpectrometerParametersFullDataAcquisitionPanel, 'numeric');
            app.SpectralResolutionnmpixelEditField.ValueChangedFcn = createCallbackFcn(app, @SpectralResolutionnmpixelEditFieldValueChanged, true);
            app.WavelengthnmEditFieldLabel = uilabel(app.SpectrometerParametersFullDataAcquisitionPanel);
            app.WavelengthnmEditFieldLabel.Text = 'Wavelength (nm)';
            app.WavelengthnmEditField = uieditfield(app.SpectrometerParametersFullDataAcquisitionPanel, 'numeric');
            app.WavelengthnmEditField.ValueChangedFcn = createCallbackFcn(app, @WavelengthnmEditFieldValueChanged, true);
            app.DataVisualisationTab = uitab(app.TabGroup2);
            app.DataVisualisationTab.Title = 'Data Visualisation';
            app.UIDataVisualisation = uiaxes(app.DataVisualisationTab);
            title(app.UIDataVisualisation, 'Title')
            xlabel(app.UIDataVisualisation, 'X')
            ylabel(app.UIDataVisualisation, 'Y')
            zlabel(app.UIDataVisualisation, 'Z')
            app.UIDataVisualisation_2 = uiaxes(app.DataVisualisationTab);
            title(app.UIDataVisualisation_2, 'Title')
            xlabel(app.UIDataVisualisation_2, 'X')
            ylabel(app.UIDataVisualisation_2, 'Y')
            zlabel(app.UIDataVisualisation_2, 'Z')
            app.ReadFilePanel = uipanel(app.DataVisualisationTab);
            app.ReadFilePanel.Title = 'Read File';
            app.UITable = uitable(app.ReadFilePanel);
            app.UITable.ColumnName = {'Field'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.FileNameEditFieldLabel = uilabel(app.ReadFilePanel);
            app.FileNameEditFieldLabel.Text = 'FileName';
            app.FileNameEditField = uieditfield(app.ReadFilePanel, 'text');
            app.FileNameEditField.ValueChangedFcn = createCallbackFcn(app, @FileNameEditFieldValueChanged, true);
            app.ReadButton = uibutton(app.ReadFilePanel, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.TabGroup3 = uitabgroup(app.DataVisualisationTab);
            app.SpectrumTab = uitab(app.TabGroup3);
            app.SpectrumTab.Title = 'Spectrum';
            app.PhiValueEditFieldLabel = uilabel(app.SpectrumTab);
            app.PhiValueEditFieldLabel.Text = 'Phi Value';
            app.PhiValueEditField = uieditfield(app.SpectrumTab, 'numeric');
            app.PhiValueEditField.ValueChangedFcn = createCallbackFcn(app, @PhiValueEditFieldValueChanged, true);
            app.ThetaValueEditFieldLabel = uilabel(app.SpectrumTab);
            app.ThetaValueEditFieldLabel.Text = 'Theta Value';
            app.ThetaValueEditField = uieditfield(app.SpectrumTab, 'numeric');
            app.ThetaValueEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaValueEditFieldValueChanged, true);
            app.DisplaySpectrumButton = uibutton(app.SpectrumTab, 'push');
            app.DisplaySpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySpectrumButtonPushed, true);
            app.DisplaySpectrumButton.Text = 'Display';
            app.SinglePhiCutTab = uitab(app.TabGroup3);
            app.SinglePhiCutTab.Title = 'SinglePhiCut';
            app.PhiValueEditField_2Label = uilabel(app.SinglePhiCutTab);
            app.PhiValueEditField_2Label.Text = 'Phi Value';
            app.PixelValueEditFieldLabel = uilabel(app.SinglePhiCutTab);
            app.PixelValueEditFieldLabel.Text = 'Pixel Value';
            app.PhiPixelValueEditField = uieditfield(app.SinglePhiCutTab, 'numeric');
            app.PhiPixelValueEditField.ValueChangedFcn = createCallbackFcn(app, @PhiPixelValueEditFieldValueChanged, true);
            app.PhiCutValueEditField = uieditfield(app.SinglePhiCutTab, 'numeric');
            app.PhiCutValueEditField.ValueChangedFcn = createCallbackFcn(app, @PhiCutValueEditFieldValueChanged, true);
            app.DisplayPhiCutButton = uibutton(app.SinglePhiCutTab, 'push');
            app.DisplayPhiCutButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayPhiCutButtonPushed, true);
            app.DisplayPhiCutButton.Text = 'Display';
            app.SingleThetaCutTab = uitab(app.TabGroup3);
            app.SingleThetaCutTab.Title = 'SingleThetaCut';
            app.PixelValueEditField_2Label = uilabel(app.SingleThetaCutTab);
            app.PixelValueEditField_2Label.Text = 'Pixel Value';
            app.ThetaPixelValueEditField = uieditfield(app.SingleThetaCutTab, 'numeric');
            app.ThetaPixelValueEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaPixelValueEditFieldValueChanged, true);
            app.ThetaValueEditField_2Label = uilabel(app.SingleThetaCutTab);
            app.ThetaValueEditField_2Label.Text = 'Theta Value';
            app.ThetaCutValueEditField = uieditfield(app.SingleThetaCutTab, 'numeric');
            app.ThetaCutValueEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaCutValueEditFieldValueChanged, true);
            app.DisplayThetaCutButton = uibutton(app.SingleThetaCutTab, 'push');
            app.DisplayThetaCutButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayThetaCutButtonPushed, true);
            app.DisplayThetaCutButton.Text = 'Display';
            app.ChooseGraphButtonGroup = uibuttongroup(app.DataVisualisationTab);
            app.ChooseGraphButtonGroup.Title = 'Choose Graph';
            app.Graph2Button = uiradiobutton(app.ChooseGraphButtonGroup);
            app.Graph2Button.Text = 'Graph 2';
            app.Graph1Button = uiradiobutton(app.ChooseGraphButtonGroup);
            app.Graph1Button.Text = 'Graph 1';
            app.Graph1Button.Value = true;
            app.ClearButton = uibutton(app.DataVisualisationTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SystemApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end